classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RunButton                    matlab.ui.control.Button
        AnsEditField_4               matlab.ui.control.EditField
        AnsEditField_2               matlab.ui.control.EditField
        AnsEditField_3               matlab.ui.control.EditField
        AnsEditField                 matlab.ui.control.EditField
        KtquthucLabel                matlab.ui.control.Label
        FpEditField                  matlab.ui.control.EditField
        FpLabel                      matlab.ui.control.Label
        TypeDropDown                 matlab.ui.control.DropDown
        PhngphpxlDropDownLabel       matlab.ui.control.Label
        LimitbField                  matlab.ui.control.EditField
        LimitaField                  matlab.ui.control.EditField
        Limitlabel                   matlab.ui.control.Label
        ButtonGroup                  matlab.ui.container.ButtonGroup
        Ex11Button                   matlab.ui.control.Button
        Ex10Button                   matlab.ui.control.Button
        Ex9Button                    matlab.ui.control.Button
        Ex8Button                    matlab.ui.control.Button
        Switch                       matlab.ui.control.Switch
        FunctionField                matlab.ui.control.EditField
        HmhocdliucnxlEditFieldLabel  matlab.ui.control.Label
        MnhcPhngphptnhvMatlabLabel   matlab.ui.control.Label
        BOCOGIAKLabel                matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        Image                        matlab.ui.control.Image
        UIAxes                       matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Ex8Button
        function Ex8ButtonPushed(app, event)
            cla(app.UIAxes,"reset");
            app.FpEditField.Position = [99,277,219,28];
            app.FpLabel.Position = [29,280,65,22];
            app.Limitlabel.Position = [30,381,87,22];
            app.LimitaField.Position = [146,380,28,24];
            app.Limitlabel.Text = 'Giới hạn [a,b]: ';
            app.FpLabel.Visible = "off";
            app.Ex8Button.Text = "Bài tập 3.3";
            app.Ex9Button.Text = 'Bài tập chương 9';
            app.Ex10Button.Text = 'Bài tập chương 10';
            app.Ex11Button.Text = 'Bài tập chương 11 + 12';
            app.TypeDropDown.Items = {'Phương pháp tiếp tuyến','Phương pháp lặp','Phương pháp chia đôi','Phương pháp dây cung'};
            app.FunctionField.Value = 'sqrt(1.65).*x.^3+1.12.*x.^2+14.35.*x-12.75';
            app.LimitaField.Value = '0.5';
            app.LimitbField.Value = '1.5';
            app.FpEditField.Value = '(-sqrt(1.65).*x.^3-1.12.*x.^2+12.75)/14.35';
            app.FpEditField.Visible = "off";
            app.Switch.Visible = "off";
            app.AnsEditField_2.Visible= "off";
            app.AnsEditField_3.Visible= "off";
            app.AnsEditField_4.Visible= "off";
            app.Limitlabel.Visible = "on";
        end

        % Button pushed function: Ex9Button
        function Ex9ButtonPushed(app, event)
            cla(app.UIAxes,"reset");
            app.Switch.Visible = "off";
            app.Ex8Button.Text = 'Bài tập chương 8';
            app.Ex9Button.Text = 'Bài tập 4.2';
            app.Ex10Button.Text = 'Bài tập chương 10';
            app.Ex11Button.Text = 'Bài tập chương 11 + 12';
            app.TypeDropDown.Items = {'Phương pháp Gauss-Jordan','Phương pháp Gauss','Phương pháp phân tích LU','Phương pháp Cholesky','Phương pháp Jacobi','Phương pháp Gauss-Seiden','Phương pháp Gauss-trụ tối đại'};
            app.FpEditField.Visible = "on";
            app.FpLabel.Visible = "on";
            app.Limitlabel.Visible = "off";
            app.LimitaField.Visible = "off";
            app.FpEditField.Position = [98,378,219,28];
            app.FpLabel.Position = [34,381,65,22];
            app.FunctionField.Value = '6 -5 7 8;3 -11 2 4;3 2 3 4;1 1 1 0';
            app.FpEditField.Value = '3;6;1;0';
            app.FpLabel.Text = 'Nhập b:';
            app.AnsEditField.Position = [27,193,102,30];
            app.AnsEditField_2.Visible= "on";
            app.AnsEditField_3.Visible= "on";
            app.AnsEditField_4.Visible= "on";
        end

        % Button pushed function: Ex10Button
        function Ex10ButtonPushed(app, event)
            cla(app.UIAxes,"reset");
            app.Ex8Button.Text = 'Bài tập chương 8';
            app.Ex9Button.Text = 'Bài tập chương 9';
            app.Ex10Button.Text = 'Bài tập 5.8';
            app.Ex11Button.Text = 'Bài tập chương 11 + 12';
            app.Switch.Visible = "on";
            app.Switch.Items = {'Nội suy','Hồi quy'};
            app.TypeDropDown.Items = {'Nội suy Lagrange','Nội suy tuyến tính','Nội suy Newton','Nội suy Spline'};
            app.FpEditField.Visible = "on";
            app.Limitlabel.Visible = "on";
            app.LimitaField.Visible = "on";
            app.FpEditField.Position = [98,378,219,28];
            app.FpLabel.Position = [34,381,65,22];
            app.AnsEditField.Position = [27,193,290,30];
            app.FpLabel.Visible = "on";
            app.FpLabel.Text = 'Nhập y:';
            app.Limitlabel.Text = 'Giá trị x:';
            app.Limitlabel.Position = [29,280,65,22];
            app.LimitaField.Position = [99,277,44,28];
            app.FunctionField.Value = '6.3 6.72 7.11 7.56 7.98 8.1';
            app.FpEditField.Value = '21.4259 23.377 25.3622 27.3831 29.138 31.5253';
            app.LimitaField.Value = '6.51';
            app.AnsEditField_2.Visible= "on";
            app.AnsEditField_3.Visible= "off";
            app.AnsEditField_4.Visible= "off";
        end

        % Button pushed function: Ex11Button
        function Ex11ButtonPushed(app, event)
            cla(app.UIAxes,"reset");
            app.Ex8Button.Text = 'Bài tập chương 8';
            app.Ex9Button.Text = 'Bài tập chương 9';
            app.Ex10Button.Text = 'Bài tập chương 10';
            app.Ex11Button.Text = 'Bài tập 6.1';
            app.Switch.Visible = "on";
            app.Switch.Items = {'Đạo hàm','Tích phân'};
            app.TypeDropDown.Items = {'Taylor','Ngoại suy Richardson'};
            app.FpEditField.Visible = "on";
            app.Limitlabel.Visible = "on";
            app.LimitaField.Visible = "on";
            app.FpEditField.Position = [98,378,219,28];
            app.FpLabel.Position = [34,381,65,22];
            app.AnsEditField.Position = [27,193,102,30];
            app.FpLabel.Visible = "on";
            app.FpLabel.Text = 'Nhập y:';
            app.Limitlabel.Text = 'Giá trị x:';
            app.Limitlabel.Position = [29,280,65,22];
            app.LimitaField.Position = [99,277,44,28];
            app.FunctionField.Value = '6.3 6.72 7.11 7.56 7.98 8.1';
            app.FpEditField.Value = '21.4259 23.377 25.3622 27.3831 29.138 31.5253';
            app.LimitaField.Value = '6.72';
            app.AnsEditField_2.Visible= "off";
            app.AnsEditField_3.Visible= "off";
            app.AnsEditField_4.Visible= "off";
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Nội suy"
                app.TypeDropDown.Items = {'Nội suy Lagrange','Nội suy tuyến tính','Nội suy Newton','Nội suy Spline'};
            elseif value == "Hồi quy"
                app.TypeDropDown.Items = {'Hồi quy tuyến tính','Hồi quy hàm e mũ','Hồi quy logarit'};
            elseif value == "Đạo hàm"
                app.TypeDropDown.Items = {'Taylor','Ngoại suy Richardson'};
            else 
                app.TypeDropDown.Items = {'Tích phân hình thang','Tích phân Simpson 1/3','Tích phân Simpson 3/8','Tích phân Gauss-Legender'};
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            fx = app.FunctionField.Value;
            type_of = app.TypeDropDown.Value;
            switch type_of
                case "Phương pháp chia đôi"
%                     a = str2double(app.LimitaField.Value);
%                     b = str2double(app.LimitbField.Value);  
%                     answer = chiadoi(fx,a,b,0.002);
%                     index = 1;
                case "Phương pháp lặp"
                    a = str2double(app.LimitaField.Value);
                    b = str2double(app.LimitbField.Value);
                    fp = app.FpEditField.Value;
                    answer = lap(fx,fp,a,b,0.002);
                    index = 1;
                case "Phương pháp tiếp tuyến"
                    a = str2double(app.LimitaField.Value);
                    b = str2double(app.LimitbField.Value);
                    answer = PPTiepTuyen(fx,a,b,0.002);
                    index = 1;
                case "Phương pháp dây cung"
%                     a = str2double(app.LimitaField.Value);
%                     b = str2double(app.LimitbField.Value);
%                     answer = daycung(fx,a,b,0.002);
%                     index = 1;
                case "Phương pháp Gauss"
%                     A = str2num(fx);
%                     fp = app.FpEditField.Value;
%                     b = str2num(fp);
%                     answer = gauss(A,b);
%                     index = 1;
                case "Phương pháp Gauss-Jordan"
                    a = str2num(fx);
                    fp = app.FpEditField.Value;
                    b = str2num(fp);
                    answer = gauss_jordan(a,b);
                    index = 17;
                case "Phương pháp Gauss-trụ tối đại"
%                     a = str2num(fx);
%                     fp = app.FpEditField.Value;
%                     b = str2num(fp);
%                     answer = GAUSS_ELIM(a,b);
%                     index = 1;
                case "Phương pháp phân tích LU"
%                     a = str2num(fx);
%                     fp = app.FpEditField.Value;
%                     b = str2num(fp);
%                     answer  = LUdec_sol(a,b);
%                     index = 1;
                case "Nội suy Lagrange"
                    x_data = str2num(fx);
                    x_predict = str2num(app.LimitaField.Value);
                    y_data = str2num(app.FpEditField.Value);
                    v = Lagrange(x_data,y_data);
                    app.AnsEditField.Value = string(v);
                    u = Lagrange(x_data,y_data,x_predict);
                    txt = sprintf('y = %.3f',u);
                    app.AnsEditField_2.Value = txt;
                    v = matlabFunction(v);
                    y = v(x_data);
                    plot(app.UIAxes,x_data,y_data,'bo',x_data,y);
                case "Taylor"
                    x_data = str2num(app.FunctionField.Value);
                    y_data = str2num(app.FpEditField.Value);
                    x_predict = str2num(app.LimitaField.Value);
                    answer = TaylorSr(x_data,y_data,x_predict);
                    app.AnsEditField.Value = string(answer);
            end
            if app.Ex8Button.Text == "Bài tập 3.3"
                app.AnsEditField.Value = string(answer(index));
                y = str2func(['@(x)',fx]);
                x = a:0.01:b;
                y=y(x);
                plot(app.UIAxes,x,y); 
                grid(app.UIAxes,"on");
            elseif app.Ex9Button.Text == "Bài tập 4.2"
                app.AnsEditField.Value = string(answer(index));
                app.AnsEditField_2.Value = string(answer(index+1));
                app.AnsEditField_3.Value = string(answer(index+2));
                app.AnsEditField_4.Value = string(answer(index+3));
            end
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            if value == "Phương pháp lặp" || app.Ex9Button.Text == "Bài tập 4.2" || app.Ex10Button.Text == "Bài tập 5.8"
                app.FpEditField.Visible = "on";
                app.FpLabel.Visible =  "on";
            else 
                app.FpEditField.Visible = "off";
                app.FpLabel.Visible =  "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Dồ Thị ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo-chinh-e1681638380305.png');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Lớp: 21DTV_CLC1'; 'Họ và Tên SV: Nguyễn Minh Quân'; 'Mã số SV:21207204'};
            app.BOCOGIAKLabel = uilabel(app.UIFigure);
            app.BOCOGIAKLabel.Text = 'BÁO CÁO GIỮA KÌ ';
            app.MnhcPhngphptnhvMatlabLabel = uilabel(app.UIFigure);
            app.MnhcPhngphptnhvMatlabLabel.Text = 'Môn học: Phương pháp tính và Matlab';
            app.HmhocdliucnxlEditFieldLabel = uilabel(app.UIFigure);
            app.HmhocdliucnxlEditFieldLabel.Text = 'Hàm hoặc dữ liệu cần xử lý:';
            app.FunctionField = uieditfield(app.UIFigure, 'text');
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'on', 'off'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Visible = 'off';
            app.Switch.Value = 'on';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.Ex8Button = uibutton(app.ButtonGroup, 'push');
            app.Ex8Button.ButtonPushedFcn = createCallbackFcn(app, @Ex8ButtonPushed, true);
            app.Ex8Button.Text = 'Bài tập chương 8';
            app.Ex9Button = uibutton(app.ButtonGroup, 'push');
            app.Ex9Button.ButtonPushedFcn = createCallbackFcn(app, @Ex9ButtonPushed, true);
            app.Ex9Button.Text = 'Bài tập chương 9';
            app.Ex10Button = uibutton(app.ButtonGroup, 'push');
            app.Ex10Button.ButtonPushedFcn = createCallbackFcn(app, @Ex10ButtonPushed, true);
            app.Ex10Button.Text = 'Bài tập chương 10';
            app.Ex11Button = uibutton(app.ButtonGroup, 'push');
            app.Ex11Button.ButtonPushedFcn = createCallbackFcn(app, @Ex11ButtonPushed, true);
            app.Ex11Button.Text = 'Bài tập chương 11 + 12';
            app.Limitlabel = uilabel(app.UIFigure);
            app.Limitlabel.Text = 'Giới hạn [a,b]:';
            app.LimitaField = uieditfield(app.UIFigure, 'text');
            app.LimitaField.Placeholder = 'a';
            app.LimitbField = uieditfield(app.UIFigure, 'text');
            app.LimitbField.Placeholder = 'b';
            app.PhngphpxlDropDownLabel = uilabel(app.UIFigure);
            app.PhngphpxlDropDownLabel.Text = 'Phương pháp xử lý:';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = {};
            app.FpLabel = uilabel(app.UIFigure);
            app.FpLabel.Visible = 'off';
            app.FpLabel.Text = 'Hàm lặp: ';
            app.FpEditField = uieditfield(app.UIFigure, 'text');
            app.FpEditField.Visible = 'off';
            app.KtquthucLabel = uilabel(app.UIFigure);
            app.KtquthucLabel.Text = 'Kết quả thu được';
            app.AnsEditField = uieditfield(app.UIFigure, 'text');
            app.AnsEditField.Editable = 'off';
            app.AnsEditField_3 = uieditfield(app.UIFigure, 'text');
            app.AnsEditField_3.Editable = 'off';
            app.AnsEditField_3.Visible = 'off';
            app.AnsEditField_2 = uieditfield(app.UIFigure, 'text');
            app.AnsEditField_2.Editable = 'off';
            app.AnsEditField_2.Visible = 'off';
            app.AnsEditField_4 = uieditfield(app.UIFigure, 'text');
            app.AnsEditField_4.Editable = 'off';
            app.AnsEditField_4.Visible = 'off';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end